classdef GUIV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PulseGeneratorUIFigure          matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        MuscleResistanceCheckBox        matlab.ui.control.CheckBox
        CalibratedLamp                  matlab.ui.control.Lamp
        CalibratedLamp_2Label           matlab.ui.control.Label
        XAxisDropDown                   matlab.ui.control.DropDown
        XAxisDropDownLabel              matlab.ui.control.Label
        AgainstLabel                    matlab.ui.control.Label
        PlotLabel                       matlab.ui.control.Label
        DataSetLamp                     matlab.ui.control.Lamp
        DataSetLampLabel                matlab.ui.control.Label
        LoadCalibrationSessionButton    matlab.ui.control.Button
        FootDorsiPlantarCheckBox        matlab.ui.control.CheckBox
        FootInversionEversionCheckBox   matlab.ui.control.CheckBox
        ShinDorsiPlantarCheckBox        matlab.ui.control.CheckBox
        ShinInversionEversionCheckBox   matlab.ui.control.CheckBox
        JointAngleDorsiPlantarCheckBox  matlab.ui.control.CheckBox
        JointAngleInversionEversionCheckBox  matlab.ui.control.CheckBox
        OpenDataSetButton               matlab.ui.control.Button
        UnfilteredVelocityCheckBox      matlab.ui.control.CheckBox
        FilteredVelocityCheckBox        matlab.ui.control.CheckBox
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        plotFootPitch
        plotShinPitch
        plotFootRoll
        plotShinRoll
        plotJointAnglePitch
        plotJointAngleRoll
        plotFilteredVelocityRoll
        plotFilteredVelocityPitch
        plotUnfilteredVelocityRoll
        plotUnfilteredVelocityPitch
        plotMuscleResistance
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DataSetLamp.Color = [1 0 0];
            app.CalibratedLamp.Color = [1 0 0];
        end

        % Value changed function: FilteredVelocityCheckBox
        function FilteredVelocityCheckBoxValueChanged(app, event)
            value = app.FilteredVelocityCheckBox.Value;
            if (value == 1)
                app.plotFilteredVelocityRoll.Visible = 'on';
                app.plotFilteredVelocityPitch.Visible = 'on';
            else
                app.plotFilteredVelocityRoll.Visible = 'off';
                app.plotFilteredVelocityPitch.Visible = 'off';
            end
        end

        % Value changed function: UnfilteredVelocityCheckBox
        function UnfilteredVelocityCheckBoxValueChanged(app, event)
            value = app.UnfilteredVelocityCheckBox.Value;
            if (value == 1)
                app.plotUnfilteredVelocityRoll.Visible = 'on';
                app.plotUnfilteredVelocityPitch.Visible = 'on';
            else
                app.plotUnfilteredVelocityRoll.Visible = 'off';
                app.plotUnfilteredVelocityPitch.Visible = 'off';
            end
        end

        % Button pushed function: OpenDataSetButton
        function OpenDataSetButtonPushed(app, event)
            % Prompt the user to select the data file that they wish to open.
            [file, path] = uigetfile({ '*.csv;*.txt', 'STRASTech Data Files (*.csv,*.txt)'; }, 'Select a Data Set');
            fullfilepath = strcat(path, file);

            M = csvread(fullfilepath, 1);

            % Allow other functions to see the variables for plotting.
            global time;
            global faccx faccy faccz fgyrx fgyry fgyrz;
            global saccx saccy saccz sgyrx sgyry sgyrz;
            global fgyrxo fgyryo fgyrzo sgyrxo sgyryo sgyrzo; % offsets from calibration procedure.
            global load;
            global fRoll fPitch sRoll sPitch fAccRoll fAccPitch sAccRoll sAccPitch;
            global roll pitch vAngRoll vAngPitch vAngRollFiltered vAngPitchFiltered;

            time = M(:, 1);
            
            d = zeros(size(time));
            
            % Remove salt and pepper noise for all IMU data.
            for j = 2:13
                for i = 2:size(time)-1
                    d(i) = M(i, j) - M(i - 1, j);
                    if (abs(d(i) - d(i - 1)) > 500)
                        M(i, j) = (M(i - 1, j) + M(i + 1, j)) ./ 2;
                    end
                end
            end
            
            faccx = M(:, 2);
            faccx = faccx .* (2.0 / 32768.0);
            faccy = M(:, 3);
            faccy = faccy .* (2.0 / 32768.0);
            faccz = M(:, 4);
            faccz = faccz .* (2.0 / 32768.0);
            fgyrx = M(:, 5);
            fgyrx = fgyrx .* (2000.0 / 32768.0);
            fgyry = M(:, 6);
            fgyry = fgyry .* (2000.0 / 32768.0);
            fgyrz = M(:, 7);
            fgyrz = fgyrz .* (2000.0 / 32768.0);
            
            saccx = M(:, 8);
            saccx = saccx .* (2.0 / 32768.0);
            saccy = M(:, 9);
            saccy = saccy .* (2.0 / 32768.0);
            saccz = M(:, 10);
            saccz = saccz .* (2.0 / 32768.0);
            sgyrx = M(:, 11);
            sgyrx = sgyrx .* (2000.0 / 32768.0);
            sgyry = M(:, 12);
            sgyry = sgyry .* (2000.0 / 32768.0);
            sgyrz = M(:, 13);
            sgyrz = sgyrz .* (2000.0 / 32768.0);

            load = (-M(:, 14) + -M(:, 15)) ./ 2;

            fgyrx = fgyrx - fgyrxo;
            fgyry = fgyry - fgyryo;
            fgyrz = fgyrz - fgyrzo;
            sgyrx = sgyrx - sgyrxo;
            sgyry = sgyry - sgyryo;
            sgyrz = sgyrz - sgyrzo;
            
            % Normalize the time to start at 0 ms.
            for i = 2:size(time)
                time(i) = time(i) - time(1);
                load(i) = load(i) - load(1);
            end
            time(1) = 0;
            load(1) = 0;

            load = lowpass(load, 0.01);
            
            % Initialize variables.
            fRoll = zeros(size(time));
            fPitch = zeros(size(time));
            sRoll = zeros(size(time));
            sPitch = zeros(size(time));
            fAccRoll = zeros(size(time));
            fAccPitch = zeros(size(time));
            sAccRoll = zeros(size(time));
            sAccPitch = zeros(size(time));
            
            % Calculate angles for accelerometer and gyroscope independently.
            for i = 1:size(time)
                % Get foot accelerometer roll and pitch.
                fAccRoll(i) = atan2(faccy(i), faccz(i));
                fAccPitch(i) = atan2(faccx(i), sqrt(faccy(i) .^ 2 + faccz(i) .^ 2));
            
                % Get shin accelerometer roll and pitch.
                sAccRoll(i) = atan2(saccy(i), saccz(i));
                sAccPitch(i) = atan2(saccx(i), sqrt(saccy(i) .^ 2 + saccz(i) .^ 2));
            
                if i == 1
                    % Set initial roll and pitch according to accelerometer only.
                    fRoll(1) = fAccRoll(1);
                    fPitch(1) = fAccPitch(1);
                    sRoll(1) = sAccRoll(1);
                    sPitch(1) = sAccPitch(1);
                else
                    % Get gyroscope roll and pitch.
                    fRoll(i) = fRoll(i - 1) + fgyrx(i) * (pi / 180) * ((time(i) - time(i - 1)) / 1000);
                    fPitch(i) = fPitch(i - 1) + fgyry(i) * (pi / 180) * ((time(i) - time(i - 1)) / 1000);
                    sRoll(i) = sRoll(i - 1) + sgyrx(i) * (pi / 180) * ((time(i) - time(i - 1)) / 1000);
                    sPitch(i) = sPitch(i - 1) + sgyry(i) * (pi / 180) * ((time(i) - time(i - 1)) / 1000);
                end
            end
            
            % Cancel pitch (ROLL) flipping issue -180 / 180
            for i = 1:size(time) - 1
                diffSRoll = sAccRoll(i + 1) - sAccRoll(i);
                diffFRoll = fAccRoll(i + 1) - fAccRoll(i);
                if diffSRoll > pi
                    for j = i+1:size(time)
                        sAccRoll(j) = sAccRoll(j) - 2 * pi;
                    end
                end
                if diffSRoll < -pi
                    for j = i+1:size(time)
                        sAccRoll(j) = sAccRoll(j) + 2 * pi;
                    end
                end
                if diffFRoll > pi
                    for j = i+1:size(time)
                        fAccRoll(j) = fAccRoll(j) - 2 * pi;
                    end
                end
                if diffFRoll < -pi
                    for j = i+1:size(time)
                        fAccRoll(j) = fAccRoll(j) + 2 * pi;
                    end
                end
            end
            
            % Calculate the joint angle using sensor fusion (complementary filter algorithm).
            for i = 1:size(time)
                % Comp filter algorithm.
                a = 1.00;
            
                while (fRoll(i) - fAccRoll(i) > 360)
                    fAccRoll(i) = fAccRoll(i) - 360;
                end
                while (fRoll(i) - fAccRoll(i) < -360)
                    fAccRoll(i) = fAccRoll(i) + 360;
                end
                
                fRoll(i) = a * fRoll(i) + (1 - a) * fAccRoll(i);
                fPitch(i) = a * fPitch(i) + (1 - a) * fAccPitch(i);
                sRoll(i) = a * sRoll(i) + (1 - a) * sAccRoll(i);
                sPitch(i) = a * sPitch(i) + (1 - a) * sAccPitch(i);
            end
            
            % Convert from radians to degrees.
            fPitch = fPitch * 180 / pi;
            fRoll = fRoll * 180 / pi;
            sPitch = sPitch * 180 / pi;
            sRoll = sRoll * 180 / pi;
            
            % Calculate the joint angle in degrees.
            jointAngleOffset = 0;
            roll = sRoll - fRoll + jointAngleOffset;
            pitch = sPitch - fPitch + jointAngleOffset;
            
            % Integrate to find the velocity in m/s.
            vAngRoll = zeros(size(time));
            vAngPitch = zeros(size(time));
            for i = 2:size(time)
                vAngPitch(i) = (pitch(i) - pitch(i - 1)) / ((time(i) - time(i - 1)) / 1000);
                vAngRoll(i) = (roll(i) - roll(i - 1)) / ((time(i) - time(i - 1)) / 1000);
            end

            vAngPitchFiltered = lowpass(vAngPitch, 0.002);
            vAngRollFiltered = lowpass(vAngRoll, 0.002);

            app.DataSetLamp.Color = [0 1 0];

            hold(app.UIAxes,'on');
            app.plotFootPitch = plot(app.UIAxes, time, fPitch, 'Color', [0 0.4470 0.7410], 'LineWidth', 2);
            app.plotShinPitch = plot(app.UIAxes, time, sPitch, 'Color', [0 0.4470 0.7410], 'LineWidth', 2);
            app.plotFootRoll = plot(app.UIAxes, time, fRoll, 'Color', [0 0.4470 0.7410], 'LineWidth', 2);
            app.plotShinRoll = plot(app.UIAxes, time, sRoll, 'Color', [0 0.4470 0.7410], 'LineWidth', 2);
            app.plotJointAnglePitch = plot(app.UIAxes, time, pitch, 'Color', [0 0.4470 0.7410], 'LineWidth', 2);
            app.plotJointAngleRoll = plot(app.UIAxes, time, roll, 'Color', [0 0.4470 0.7410], 'LineWidth', 2);
            app.plotUnfilteredVelocityRoll = plot(app.UIAxes, time, vAngRoll, 'Color', [0 0.4470 0.7410], 'LineWidth', 2);
            app.plotUnfilteredVelocityPitch = plot(app.UIAxes, time, vAngPitch, 'Color', [0 0.4470 0.7410], 'LineWidth', 2);
            app.plotFilteredVelocityRoll = plot(app.UIAxes, time, vAngRollFiltered, 'Color', [0 0.4470 0.7410], 'LineWidth', 2);
            app.plotFilteredVelocityPitch = plot(app.UIAxes, time, vAngPitchFiltered, 'Color', [0 0.4470 0.7410], 'LineWidth', 2);
            app.plotMuscleResistance = plot(app.UIAxes, time, load, 'Color', [0 0.4470 0.7410], 'LineWidth', 2);
            
            app.plotFootPitch.Visible = 'off';
            app.plotShinPitch.Visible = 'off';
            app.plotFootRoll.Visible = 'off';
            app.plotShinRoll.Visible = 'off';
            app.plotJointAnglePitch.Visible = 'off';
            app.plotJointAngleRoll.Visible = 'off';
            app.plotFilteredVelocityRoll.Visible = 'off';
            app.plotFilteredVelocityPitch.Visible = 'off';
            app.plotUnfilteredVelocityRoll.Visible = 'off';
            app.plotUnfilteredVelocityPitch.Visible = 'off';
            app.plotMuscleResistance.Visible = 'off';
        end

        % Close request function: PulseGeneratorUIFigure
        function PulseGeneratorUIFigureCloseRequest(app, event)
            delete(app)
            clear;
        end

        % Value changed function: JointAngleInversionEversionCheckBox
        function JointAngleInversionEversionCheckBoxValueChanged(app, event)
            value = app.JointAngleInversionEversionCheckBox.Value;
            if (value == 1)
                app.plotJointAngleRoll.Visible = 'on';
            else
                app.plotJointAngleRoll.Visible = 'off';
            end
        end

        % Value changed function: JointAngleDorsiPlantarCheckBox
        function JointAngleDorsiPlantarCheckBoxValueChanged(app, event)
            value = app.JointAngleDorsiPlantarCheckBox.Value;
            if (value == 1)
                app.plotJointAnglePitch.Visible = 'on';
            else
                app.plotJointAnglePitch.Visible = 'off';
            end
        end

        % Value changed function: ShinInversionEversionCheckBox
        function ShinInversionEversionCheckBoxValueChanged(app, event)
            value = app.ShinInversionEversionCheckBox.Value;
            if (value == 1)
                app.plotShinRoll.Visible = 'on';
            else
                app.plotShinRoll.Visible = 'off';
            end
        end

        % Value changed function: ShinDorsiPlantarCheckBox
        function ShinDorsiPlantarCheckBoxValueChanged(app, event)
            value = app.ShinDorsiPlantarCheckBox.Value;
            if (value == 1)
                app.plotShinPitch.Visible = 'on';
            else
                app.plotShinPitch.Visible = 'off';
            end
        end

        % Value changed function: FootInversionEversionCheckBox
        function FootInversionEversionCheckBoxValueChanged(app, event)
            value = app.FootInversionEversionCheckBox.Value;
            if (value == 1)
                app.plotFootRoll.Visible = 'on';
            else
                app.plotFootRoll.Visible = 'off';
            end
        end

        % Value changed function: FootDorsiPlantarCheckBox
        function FootDorsiPlantarCheckBoxValueChanged(app, event)
            value = app.FootDorsiPlantarCheckBox.Value;
            if (value == 1)
                app.plotFootPitch.Visible = 'on';
            else
                app.plotFootPitch.Visible = 'off';
            end
        end

        % Button pushed function: LoadCalibrationSessionButton
        function LoadCalibrationSessionButtonPushed(app, event)
            % Prompt the user to select the calibration file that they wish to open.
            [file, path] = uigetfile({ '*.csv;*.txt', 'STRASTech Calibration Files (*.csv,*.txt)'; }, 'Select a Calibration File');
            fullfilepath = strcat(path, file);

            C = csvread(fullfilepath, 1);

            % Allow other functions to see the variables for calibration.
            global fgyrxo fgyryo fgyrzo sgyrxo sgyryo sgyrzo;

            fgyrxcal = C(:, 5);
            fgyrxcal = fgyrxcal .* (2000.0 / 32768.0);
            fgyrycal = C(:, 6);
            fgyrycal = fgyrycal .* (2000.0 / 32768.0);
            fgyrzcal = C(:, 7);
            fgyrzcal = fgyrzcal .* (2000.0 / 32768.0);
            
            sgyrxcal = C(:, 11);
            sgyrxcal = sgyrxcal .* (2000.0 / 32768.0);
            sgyrycal = C(:, 12);
            sgyrycal = sgyrycal .* (2000.0 / 32768.0);
            sgyrzcal = C(:, 13);
            sgyrzcal = sgyrzcal .* (2000.0 / 32768.0);

            fgyrxo = mean(fgyrxcal);
            fgyryo = mean(fgyrycal);
            fgyrzo = mean(fgyrzcal);

            sgyrxo = mean(sgyrxcal);
            sgyryo = mean(sgyrycal);
            sgyrzo = mean(sgyrzcal);
            
            app.CalibratedLamp.Color = [0 1 0];
        end

        % Value changed function: MuscleResistanceCheckBox
        function MuscleResistanceCheckBoxValueChanged(app, event)
            value = app.MuscleResistanceCheckBox.Value;
            if (value == 1)
                app.plotMuscleResistance.Visible = 'on';
            else
                app.plotMuscleResistance.Visible = 'off';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PulseGeneratorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {628, 628};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {286, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PulseGeneratorUIFigure = uifigure('Visible', 'off');
            app.PulseGeneratorUIFigure.Name = 'Pulse Generator';
            app.PulseGeneratorUIFigure.CloseRequestFcn = createCallbackFcn(app, @PulseGeneratorUIFigureCloseRequest, true);
            app.PulseGeneratorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PulseGeneratorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FilteredVelocityCheckBox = uicheckbox(app.LeftPanel);
            app.FilteredVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilteredVelocityCheckBoxValueChanged, true);
            app.FilteredVelocityCheckBox.Text = 'Filtered Velocity';
            app.UnfilteredVelocityCheckBox = uicheckbox(app.LeftPanel);
            app.UnfilteredVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @UnfilteredVelocityCheckBoxValueChanged, true);
            app.UnfilteredVelocityCheckBox.Text = 'Unfiltered Velocity';
            app.OpenDataSetButton = uibutton(app.LeftPanel, 'push');
            app.OpenDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDataSetButtonPushed, true);
            app.OpenDataSetButton.Text = 'Open Data Set';
            app.JointAngleInversionEversionCheckBox = uicheckbox(app.LeftPanel);
            app.JointAngleInversionEversionCheckBox.ValueChangedFcn = createCallbackFcn(app, @JointAngleInversionEversionCheckBoxValueChanged, true);
            app.JointAngleInversionEversionCheckBox.Text = 'Joint Angle Inversion / Eversion';
            app.JointAngleDorsiPlantarCheckBox = uicheckbox(app.LeftPanel);
            app.JointAngleDorsiPlantarCheckBox.ValueChangedFcn = createCallbackFcn(app, @JointAngleDorsiPlantarCheckBoxValueChanged, true);
            app.JointAngleDorsiPlantarCheckBox.Text = {'Joint Angle Dorsi / Plantar'; ''};
            app.ShinInversionEversionCheckBox = uicheckbox(app.LeftPanel);
            app.ShinInversionEversionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShinInversionEversionCheckBoxValueChanged, true);
            app.ShinInversionEversionCheckBox.Text = {'Shin Inversion / Eversion'; ''};
            app.ShinDorsiPlantarCheckBox = uicheckbox(app.LeftPanel);
            app.ShinDorsiPlantarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShinDorsiPlantarCheckBoxValueChanged, true);
            app.ShinDorsiPlantarCheckBox.Text = 'Shin Dorsi / Plantar';
            app.FootInversionEversionCheckBox = uicheckbox(app.LeftPanel);
            app.FootInversionEversionCheckBox.ValueChangedFcn = createCallbackFcn(app, @FootInversionEversionCheckBoxValueChanged, true);
            app.FootInversionEversionCheckBox.Text = 'Foot Inversion / Eversion';
            app.FootDorsiPlantarCheckBox = uicheckbox(app.LeftPanel);
            app.FootDorsiPlantarCheckBox.ValueChangedFcn = createCallbackFcn(app, @FootDorsiPlantarCheckBoxValueChanged, true);
            app.FootDorsiPlantarCheckBox.Text = 'Foot Dorsi / Plantar';
            app.LoadCalibrationSessionButton = uibutton(app.LeftPanel, 'push');
            app.LoadCalibrationSessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCalibrationSessionButtonPushed, true);
            app.LoadCalibrationSessionButton.Text = 'Load Calibration Session';
            app.DataSetLampLabel = uilabel(app.LeftPanel);
            app.DataSetLampLabel.Text = 'Data Set';
            app.DataSetLamp = uilamp(app.LeftPanel);
            app.PlotLabel = uilabel(app.LeftPanel);
            app.PlotLabel.Text = 'Plot';
            app.AgainstLabel = uilabel(app.LeftPanel);
            app.AgainstLabel.Text = 'Against';
            app.XAxisDropDownLabel = uilabel(app.LeftPanel);
            app.XAxisDropDownLabel.Text = 'X-Axis';
            app.XAxisDropDown = uidropdown(app.LeftPanel);
            app.XAxisDropDown.Items = {'TIme', 'Muscle Resistance', 'Joint Angle Dorsi / Plantar', 'Foot Dorsi / Plantar', 'Shin Dorsi / Plantar', 'Foot Inversion / Eversion', 'Shin Inversion / Eversion'};
            app.XAxisDropDown.Value = 'TIme';
            app.CalibratedLamp_2Label = uilabel(app.LeftPanel);
            app.CalibratedLamp_2Label.Text = {'Calibrated'; ''};
            app.CalibratedLamp = uilamp(app.LeftPanel);
            app.MuscleResistanceCheckBox = uicheckbox(app.LeftPanel);
            app.MuscleResistanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @MuscleResistanceCheckBoxValueChanged, true);
            app.MuscleResistanceCheckBox.Text = 'Muscle Resistance';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X Axis')
            ylabel(app.UIAxes, 'Y Axis')
            % Show the figure after all components are created
            app.PulseGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PulseGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PulseGeneratorUIFigure)
        end
    end
end